classdef SimExp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure             matlab.ui.Figure
    GridLayout           matlab.ui.container.GridLayout
    LeftPanel            matlab.ui.container.Panel
    PlantLabel           matlab.ui.control.Label
    ControllerLabel      matlab.ui.control.Label
    EstimatorLabel       matlab.ui.control.Label
    ReferenceLabel       matlab.ui.control.Label
    SensorLabel          matlab.ui.control.Label
    ModelLabel           matlab.ui.control.Label
    RunmodeListBox       matlab.ui.control.ListBox
    RunmodeListBoxLabel  matlab.ui.control.Label
    ModeDropDown         matlab.ui.control.DropDown
    ModeDropDownLabel    matlab.ui.control.Label
    CenterPanel          matlab.ui.container.Panel
    InstructionLabel     matlab.ui.control.Label
    StartButton          matlab.ui.control.StateButton
    TimeSlider           matlab.ui.control.Slider
    TimeSliderLabel      matlab.ui.control.Label
    Lamp                 matlab.ui.control.Lamp
    LampLabel            matlab.ui.control.Label
    RightPanel           matlab.ui.container.Panel
    DescriptionLabel     matlab.ui.control.Label
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
    twoPanelWidth = 768;
  end

    
    properties (Access = private)
      fStart = 0;  
      fExp = 0; % 0: simulation, 1: experiment 
        fMotive = 0;
        fOffline = 0;
        fDebug = 0;
        ts
        dt
        te
        logger
        env
        time = TIME();
        agent
        FH
    end
    
    methods (Access = private)
      function loop(app)
        while app.fStart
              app.InstructionLabel.Text = "Start! Press key code";
          cha = get(app.FH, 'currentcharacter'); 
          switch cha
            case 'a'
              app.InstructionLabel.Text = "Arming";
              app.Lamp.Color = [1 0 0];
            case 't'
              app.InstructionLabel.Text = "Take-off";
            case 'f'
              app.InstructionLabel.Text = "Flight";
            case 'l'
              app.InstructionLabel.Text = "Landing";
            case 's'
              app.InstructionLabel.Text = "Stop propellas";
            case {'q',' '}
              app.InstructionLabel.Text = "Quit the trial";
              break;
          end
        end
        app.Lamp.Color = [0 0 0];
      end
    end

  % Callbacks that handle component events
  methods (Access = private)

    % Value changed function: RunmodeListBox
    function switch_run_mode(app, event)
            value = app.RunmodeListBox.Value;
            if strcmp(value,"Experiment")
                app.fExp = 1;
            else
                app.fExp = 0;
            end
    end

    % Drop down opening function: ModeDropDown
    function read_modes(app, event)
            %app.ModeDropDown.Items = readcell("test.csv");
            if app.fExp
                app.ModeDropDown.Items = evalin('base','ExpBaseMode');
            else
                app.ModeDropDown.Items = evalin('base','SimBaseMode');
            end
    end

    % Value changed function: ModeDropDown
    function set_mode(app, event)
            value = app.ModeDropDown.Value;
            run(value);
            app.agent = agent;
            app.PlantLabel.Text = ["Plant : ", agent.plant.name];
            app.ModelLabel.Text = ["Model : ",agent.model.name];
            app.SensorLabel.Text = ["Sensor : ",agent.sensor.name];
            app.ReferenceLabel.Text = ["Reference : ",agent.reference.name];
            app.ControllerLabel.Text = ["Controller : ",agent.controller.name];

    end

    % Value changed function: StartButton
    function start(app, event)
      app.fStart = app.StartButton.Value;
      app.Lamp.Color = [0 1 0];       
      if isempty(app.FH)
        app.FH = figure('position', [0 0 eps eps], 'menubar', 'none');
      end
      app.InstructionLabel.Text = "Press Enter key to start.";
      waitforbuttonpress;
      app.loop();
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
      currentFigureWidth = app.UIFigure.Position(3);
      if(currentFigureWidth <= app.onePanelWidth)
          % Change to a 3x1 grid
          app.GridLayout.RowHeight = {644, 644, 644};
          app.GridLayout.ColumnWidth = {'1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 1;
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 3;
          app.RightPanel.Layout.Column = 1;
      elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
          % Change to a 2x2 grid
          app.GridLayout.RowHeight = {644, 644};
          app.GridLayout.ColumnWidth = {'1x', '1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = [1,2];
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 2;
          app.RightPanel.Layout.Column = 2;
      else
          % Change to a 1x3 grid
          app.GridLayout.RowHeight = {'1x'};
          app.GridLayout.ColumnWidth = {203, '1x', 411};
          app.LeftPanel.Layout.Row = 1;
          app.LeftPanel.Layout.Column = 1;
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 2;
          app.RightPanel.Layout.Row = 1;
          app.RightPanel.Layout.Column = 3;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.ModeDropDownLabel = uilabel(app.LeftPanel);
      app.ModeDropDownLabel.Text = 'Mode';
      app.ModeDropDown = uidropdown(app.LeftPanel);
      app.ModeDropDown.Items = {'Default'};
      app.ModeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @read_modes, true);
      app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @set_mode, true);
      app.ModeDropDown.Value = 'Default';
      app.RunmodeListBoxLabel = uilabel(app.LeftPanel);
      app.RunmodeListBoxLabel.Text = 'Run mode';
      app.RunmodeListBox = uilistbox(app.LeftPanel);
      app.RunmodeListBox.Items = {'Experiment', 'Simulation'};
      app.RunmodeListBox.ValueChangedFcn = createCallbackFcn(app, @switch_run_mode, true);
      app.RunmodeListBox.Value = 'Simulation';
      app.ModelLabel = uilabel(app.LeftPanel);
      app.ModelLabel.Text = 'Model';
      app.SensorLabel = uilabel(app.LeftPanel);
      app.SensorLabel.Text = 'Sensor';
      app.ReferenceLabel = uilabel(app.LeftPanel);
      app.ReferenceLabel.Text = 'Reference';
      app.EstimatorLabel = uilabel(app.LeftPanel);
      app.EstimatorLabel.Text = 'Estimator';
      app.ControllerLabel = uilabel(app.LeftPanel);
      app.ControllerLabel.Text = 'Controller';
      app.PlantLabel = uilabel(app.LeftPanel);
      app.PlantLabel.Text = 'Plant';
      app.CenterPanel = uipanel(app.GridLayout);
      app.LampLabel = uilabel(app.CenterPanel);
      app.LampLabel.Text = 'Lamp';
      app.Lamp = uilamp(app.CenterPanel);
      app.TimeSliderLabel = uilabel(app.CenterPanel);
      app.TimeSliderLabel.Text = 'Time';
      app.TimeSlider = uislider(app.CenterPanel);
      app.StartButton = uibutton(app.CenterPanel, 'state');
      app.StartButton.ValueChangedFcn = createCallbackFcn(app, @start, true);
      app.StartButton.Text = 'Start';
      app.InstructionLabel = uilabel(app.CenterPanel);
      app.InstructionLabel.Text = 'Instruction';
      app.RightPanel = uipanel(app.GridLayout);
      app.DescriptionLabel = uilabel(app.RightPanel);
      app.DescriptionLabel.Text = 'Description';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SimExp

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end